classdef clu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        insecondsLabel                  matlab.ui.control.Label
        ChimpLocomotionUtilityCLULabel  matlab.ui.control.Label
        tibialengthssecondLabel         matlab.ui.control.Label
        RelSpeedEditField               matlab.ui.control.NumericEditField
        RelSpeedEditFieldLabel          matlab.ui.control.Label
        CalcRelativeSpeedButton         matlab.ui.control.Button
        StrideTibiaEditField            matlab.ui.control.NumericEditField
        StrideTibiaEditFieldLabel       matlab.ui.control.Label
        StrideEditField                 matlab.ui.control.NumericEditField
        StrideEditFieldLabel            matlab.ui.control.Label
        TibiaEditField                  matlab.ui.control.NumericEditField
        TibiaEditFieldLabel             matlab.ui.control.Label
        StrideLengthButton              matlab.ui.control.Button
        CalcStrideTibiaButton           matlab.ui.control.Button
        TibiaLengthButton               matlab.ui.control.Button
        StrideDurationEditField         matlab.ui.control.NumericEditField
        StrideDurationEditFieldLabel    matlab.ui.control.Label
        SelectImageButton               matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
[file, path] = uigetfile('*.jpg;*.png', 'Select Image File');

if isequal(file, 0)
    disp('User selected cancel');
else
    disp(['User selected ', fullfile(path,file)]);
    %Displaying Video on Axes
    input_image_file = fullfile(path,file);
    imshow(input_image_file, 'Parent', app.UIAxes);
end


%X = [0,0;2,1];
%d = pdist(X,'euclidean')

        end

        % Callback function
        function ProximalLabelButtonPushed(app, event)
            
            % Draw line
            h = drawline(app.UIAxes)
            ep = h.Position;
            x1 = ep(1,1); y1 = ep(1,2); x2 = ep(2,1); y2 = ep(2,2);
            app.TextArea.Value = x1;
            

        end

        % Button pushed function: TibiaLengthButton
        function TibiaLengthButtonPushed(app, event)
            imdistline(app.UIAxes)
        end

        % Button pushed function: StrideLengthButton
        function StrideLengthButtonPushed(app, event)
            imdistline(app.UIAxes)
        end

        % Button pushed function: CalcStrideTibiaButton
        function CalcStrideTibiaButtonPushed(app, event)
            tibia = app.TibiaEditField.Value;
            stridelen = app.StrideEditField.Value;
           % "distance" refers to relative distance, i.e. distance
           % expressed as number of tibia lengths the chimp walks
           distance = stridelen / tibia;
            app.StrideTibiaEditField.Value = distance;
        end

        % Button pushed function: CalcRelativeSpeedButton
        function CalcRelativeSpeedButtonPushed(app, event)
            distance = app.StrideTibiaEditField.Value;
            time = app.StrideDurationEditField.Value;
           speed = distance / time;
            app.RelSpeedEditField.Value = speed;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.StrideDurationEditFieldLabel = uilabel(app.UIFigure);
            app.StrideDurationEditFieldLabel.Text = 'Stride Duration';
            app.StrideDurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.TibiaLengthButton = uibutton(app.UIFigure, 'push');
            app.TibiaLengthButton.ButtonPushedFcn = createCallbackFcn(app, @TibiaLengthButtonPushed, true);
            app.TibiaLengthButton.Text = 'Tibia Length';
            app.CalcStrideTibiaButton = uibutton(app.UIFigure, 'push');
            app.CalcStrideTibiaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcStrideTibiaButtonPushed, true);
            app.CalcStrideTibiaButton.Text = 'Calc. Stride/Tibia';
            app.StrideLengthButton = uibutton(app.UIFigure, 'push');
            app.StrideLengthButton.ButtonPushedFcn = createCallbackFcn(app, @StrideLengthButtonPushed, true);
            app.StrideLengthButton.Text = 'Stride Length';
            app.TibiaEditFieldLabel = uilabel(app.UIFigure);
            app.TibiaEditFieldLabel.Text = 'Tibia:';
            app.TibiaEditField = uieditfield(app.UIFigure, 'numeric');
            app.StrideEditFieldLabel = uilabel(app.UIFigure);
            app.StrideEditFieldLabel.Text = 'Stride:';
            app.StrideEditField = uieditfield(app.UIFigure, 'numeric');
            app.StrideTibiaEditFieldLabel = uilabel(app.UIFigure);
            app.StrideTibiaEditFieldLabel.Text = 'Stride/Tibia =';
            app.StrideTibiaEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcRelativeSpeedButton = uibutton(app.UIFigure, 'push');
            app.CalcRelativeSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @CalcRelativeSpeedButtonPushed, true);
            app.CalcRelativeSpeedButton.Text = 'Calc. Relative Speed';
            app.RelSpeedEditFieldLabel = uilabel(app.UIFigure);
            app.RelSpeedEditFieldLabel.Text = 'Rel. Speed =';
            app.RelSpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.tibialengthssecondLabel = uilabel(app.UIFigure);
            app.tibialengthssecondLabel.Text = '(tibia lengths / second)';
            app.ChimpLocomotionUtilityCLULabel = uilabel(app.UIFigure);
            app.ChimpLocomotionUtilityCLULabel.Text = 'Chimp Locomotion Utility (CLU)';
            app.insecondsLabel = uilabel(app.UIFigure);
            app.insecondsLabel.Text = '(in seconds)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end